classdef MTPBSO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MTPBSOUIFigure       matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        input_display        matlab.ui.control.UIAxes
        Button_2             matlab.ui.control.Button
        Label                matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        Label_2              matlab.ui.control.Label
        EditField_2          matlab.ui.control.NumericEditField
        import_info          matlab.ui.control.Label
        Tab_2                matlab.ui.container.Tab
        Button_3             matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        deltaEditFieldLabel  matlab.ui.control.Label
        deltaEditField       matlab.ui.control.NumericEditField
        EditField_9Label     matlab.ui.control.Label
        ap1_to1              matlab.ui.control.NumericEditField
        EditField_10Label    matlab.ui.control.Label
        ap1_to2              matlab.ui.control.NumericEditField
        Panel_2              matlab.ui.container.Panel
        EditField_3Label     matlab.ui.control.Label
        EditField_3          matlab.ui.control.NumericEditField
        EditField_5Label     matlab.ui.control.Label
        EditField_5          matlab.ui.control.NumericEditField
        Label_5              matlab.ui.control.Label
        EditField_6          matlab.ui.control.NumericEditField
        Label_6              matlab.ui.control.Label
        EditField_7          matlab.ui.control.NumericEditField
        Label_4              matlab.ui.control.Label
        ap2_oa               matlab.ui.control.NumericEditField
        Label_3              matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
        Panel_3              matlab.ui.container.Panel
        eEditFieldLabel      matlab.ui.control.Label
        eEditField           matlab.ui.control.NumericEditField
        betaEditFieldLabel   matlab.ui.control.Label
        betaEditField        matlab.ui.control.NumericEditField
        calcu_info           matlab.ui.control.Label
        Tab_4                matlab.ui.container.Tab
        executeButton        matlab.ui.control.Button
        joint_time           matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global model optimLog inputData outputData vrep hyperparameter
            %start_up;
            hyperparameter = hyperparameter_ap();
            model=model_serialLink();
            inputData=input_ap();
            inputData.spacenum=50;
            optimLog=optimLog_ap(10);
            outputData=output_multiSeg();
            vrep=remApi('remoteApi')'; % using the prototype file (remoteApiProto.m)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global model inputData
            app.import_info.Text='';
            
            fromVrep_ap;
            inputData.pStart = eye(4);
            inputData.pStart(1:3,4) = inputData.path(:,1);
            inputData.qStart = regular_JointPos(zeros(1,model.joint_num));
            
            app.import_info.Text='导入完成!';
            
            axis(app.input_display, 'equal')
            hold(app.input_display,'off')
            plotManipulator(model,inputData.qStart',app.input_display)
            hold(app.input_display,'on')
            % Make shape 2 (obstacles)
            for obstacle=inputData.obstacles
                S2.Vertices = obstacle.vex';
                S2.Faces = obstacle.face'+1;
                S2.FaceVertexCData = jet(size(S2.Vertices,1));
                S2.FaceColor = 'interp';
                patch(app.input_display,S2)
            end
            plot3(app.input_display,inputData.path(1,:),inputData.path(2,:),inputData.path(3,:))
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.calcu_info.Text='计算开始..';
            pause(0.1)
            planner_ap;
            app.calcu_info.Text='计算完成！';
        end

        % Callback function
        function Button_4Pushed(app, event)
            represent_ap;
        end

        % Button pushed function: executeButton
        function executeButtonPushed(app, event)
            global outputData
            plotJoint_Time(outputData, app.joint_time);

            executeInVrep;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global optimLog inputData
            value = app.EditField.Value;
            assert(value>0)
            optimLog.group_num=value;
            inputData.spacenum=optimLog.group_num*app.EditField_2.Value;
            inputData.path=regular_path(inputData.path, inputData.spacenum);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            global inputData optimLog
            value = app.EditField_2.Value;
            assert(value>0)
            inputData.spacenum=optimLog.group_num*value;
            inputData.path=regular_path(inputData.path, inputData.spacenum);
        end

        % Callback function
        function Tab_2SizeChanged(app, event)
            %position = app.Tab_2.Position;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global hyperparameter
            value = app.DropDown.Value;
            if strcmp(value,'是')
                hyperparameter.ap1_obflag=true;
                hyperparameter.ap2_obflag=true;
                app.eEditField.Enable='on';
                app.betaEditField.Enable='on';
                app.ap1_to1.Enable='on';
                app.ap2_oa.Enable='on';
            else
                hyperparameter.ap1_obflag=false;
                hyperparameter.ap2_obflag=false;
                app.eEditField.Enable='off';
                app.betaEditField.Enable='off';
                app.ap1_to1.Enable='off';
                app.ap2_oa.Enable='off';
            end
            disp(['ap1/2_obflag set to: ',num2str(hyperparameter.ap1_obflag)])
        end

        % Value changed function: eEditField
        function eEditFieldValueChanged(app, event)
            global hyperparameter
            value = app.eEditField.Value;
            hyperparameter.ob_e=value;
            disp(['ob_e set to: ',num2str(hyperparameter.ob_e)])
        end

        % Value changed function: betaEditField
        function betaEditFieldValueChanged(app, event)
            global hyperparameter
            value = app.betaEditField.Value;
            hyperparameter.ob_beta=value;
            disp(['ob_beta set to: ',num2str(hyperparameter.ob_beta)])
        end

        % Value changed function: deltaEditField
        function deltaEditFieldValueChanged(app, event)
            global hyperparameter
            value = app.deltaEditField.Value;
            hyperparameter.ap1_delta=value;
            disp(['ap1_delta set to: ',num2str(hyperparameter.ap1_delta)])
        end

        % Value changed function: ap1_to1
        function ap1_to1ValueChanged(app, event)
            value = app.ap1_to1.Value;
            global hyperparameter
            hyperparameter.ap1_to1=value;
            disp(['ap1_to1 set to: ',num2str(hyperparameter.ap1_to1)])
        end

        % Value changed function: ap1_to2
        function ap1_to2ValueChanged(app, event)
            global hyperparameter
            value = app.ap1_to2.Value;
            hyperparameter.ap1_to2=value;
            disp(['ap1_to2 set to: ',num2str(hyperparameter.ap1_to2)])
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            global hyperparameter
            value = app.EditField_3.Value;
            hyperparameter.ap2_tradeoff(1)=value;
            disp(['ap2_tradeoff set to: ',num2str(hyperparameter.ap2_tradeoff)])
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            global hyperparameter
            value = app.EditField_5.Value;
            hyperparameter.ap2_tradeoff(3)=value;
            disp(['ap2_tradeoff set to: ',num2str(hyperparameter.ap2_tradeoff)])
        end

        % Value changed function: ap2_oa
        function ap2_oaValueChanged(app, event)
            global hyperparameter
            value = app.ap2_oa.Value;
            hyperparameter.ap2_tradeoff(4)=value;
            disp(['ap2_tradeoff set to: ',num2str(hyperparameter.ap2_tradeoff)])
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            global hyperparameter
            value = app.EditField_7.Value;
            hyperparameter.ap2_tradeoff(5)=value;
            disp(['ap2_tradeoff set to: ',num2str(hyperparameter.ap2_tradeoff)])
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            global hyperparameter
            value = app.EditField_6.Value;
            hyperparameter.ap2_tradeoff(6)=value;
            disp(['ap2_tradeoff set to: ',num2str(hyperparameter.ap2_tradeoff)])
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MTPBSOUIFigure = uifigure;
            app.MTPBSOUIFigure.Name = 'MTPBSO';
            app.TabGroup = uitabgroup(app.MTPBSOUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '导入数据';
            app.input_display = uiaxes(app.Tab);
            title(app.input_display, '读入数据展示')
            xlabel(app.input_display, 'X')
            ylabel(app.input_display, 'Y')
            app.Button_2 = uibutton(app.Tab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '导入';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '轨迹分段数';
            app.EditField = uieditfield(app.Tab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 10;
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '每段轨迹采样数';
            app.EditField_2 = uieditfield(app.Tab, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 5;
            app.import_info = uilabel(app.Tab);
            app.import_info.Text = '';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '设置参数&运行算法';
            app.Button_3 = uibutton(app.Tab_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '计算';
            app.Panel = uipanel(app.Tab_2);
            app.Panel.Title = '阶段1参数';
            app.deltaEditFieldLabel = uilabel(app.Panel);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.Panel, 'numeric');
            app.deltaEditField.ValueChangedFcn = createCallbackFcn(app, @deltaEditFieldValueChanged, true);
            app.deltaEditField.Value = 0.1;
            app.EditField_9Label = uilabel(app.Panel);
            app.EditField_9Label.Text = 'Λ11';
            app.ap1_to1 = uieditfield(app.Panel, 'numeric');
            app.ap1_to1.ValueChangedFcn = createCallbackFcn(app, @ap1_to1ValueChanged, true);
            app.ap1_to1.Value = 0.333333333333333;
            app.EditField_10Label = uilabel(app.Panel);
            app.EditField_10Label.Text = 'Λ12';
            app.ap1_to2 = uieditfield(app.Panel, 'numeric');
            app.ap1_to2.ValueChangedFcn = createCallbackFcn(app, @ap1_to2ValueChanged, true);
            app.Panel_2 = uipanel(app.Tab_2);
            app.Panel_2.Title = '阶段2各指标权值';
            app.EditField_3Label = uilabel(app.Panel_2);
            app.EditField_3Label.Text = '关节限制';
            app.EditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1;
            app.EditField_5Label = uilabel(app.Panel_2);
            app.EditField_5Label.Text = '精度';
            app.EditField_5 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 1;
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '运动时间';
            app.EditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 1;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '末端路程';
            app.EditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 1;
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '避障';
            app.ap2_oa = uieditfield(app.Panel_2, 'numeric');
            app.ap2_oa.ValueChangedFcn = createCallbackFcn(app, @ap2_oaValueChanged, true);
            app.ap2_oa.Value = 1;
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '是否开启避障';
            app.DropDown = uidropdown(app.Tab_2);
            app.DropDown.Items = {'是', '否'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '是';
            app.Panel_3 = uipanel(app.Tab_2);
            app.Panel_3.Title = '避障部分参数';
            app.eEditFieldLabel = uilabel(app.Panel_3);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.Panel_3, 'numeric');
            app.eEditField.ValueChangedFcn = createCallbackFcn(app, @eEditFieldValueChanged, true);
            app.eEditField.Value = 0.001;
            app.betaEditFieldLabel = uilabel(app.Panel_3);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.Panel_3, 'numeric');
            app.betaEditField.ValueChangedFcn = createCallbackFcn(app, @betaEditFieldValueChanged, true);
            app.betaEditField.Value = 1;
            app.calcu_info = uilabel(app.Tab_2);
            app.calcu_info.Text = '';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '结果展示';
            app.executeButton = uibutton(app.Tab_4, 'push');
            app.executeButton.ButtonPushedFcn = createCallbackFcn(app, @executeButtonPushed, true);
            app.executeButton.Text = 'execute';
            app.joint_time = uiaxes(app.Tab_4);
            title(app.joint_time, '关节随时间运动变化图')
            xlabel(app.joint_time, 'X')
            ylabel(app.joint_time, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = MTPBSO

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MTPBSOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MTPBSOUIFigure)
        end
    end
end